classdef SudokuSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TextArea         matlab.ui.control.TextArea
        ResolveButton    matlab.ui.control.Button
        LoadimageButton  matlab.ui.control.Button
        UITable          matlab.ui.control.Table
        UIAxes           matlab.ui.control.UIAxes
    end

    properties (Access = private)
        a % Imagen cargada
        MSudokus % Resolución del sudoku
        num_MSudokus
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Resize = 'off';
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes.Visible = 'off';
            app.UITable.Visible = 'off';
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            [file,~]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            app.a = imread(file);
            app.UIAxes.Visible = 'on';
            imshow(app.a, 'parent', app.UIAxes);
        end

        % Button pushed function: ResolveButton
        function ResolveButtonPushed(app, event)
 % Find sudokus
    [ImSudokus,num_sudokus] = find_sudoku(app.a);
    
    % For each sudoku
    app.num_MSudokus = 0;
    
    app.MSudokus(1).Matrix = zeros(9); 
    
    for k=1:num_sudokus
        
        % Función casillas (Marta y Jorge)
        %[squares,num_squares] = find_cells(ImSudokus(k).Image);
        img = imcomplement(ImSudokus(k).Image);
        squares = find_cells(img,1);
        
        num_squares = length(squares);
 
        % Gestionar error si num_squares != 81
        if(num_squares ~= 81)
            warning('Un Sudoku detectado incorrecto')
            continue
        end

        % Detectar números (Mario)
        MaSudoku = get_numbers(squares,num_squares);
        
        % Se guarda en la estructura
        app.MSudokus(k).Matrix = MaSudoku;
        
        app.MSudokus(k).SolveMatrix = sudoku_solver(app.MSudokus(k).Matrix);
        
        % Número de Sudokus analizados
        app.num_MSudokus = num_sudokus+1;
    end
             app.UITable.Visible = 'on';
             app.UITable.Data = app.MSudokus.SolveMatrix;
             app.UITable.ColumnWidth = 'auto';
             app.UITable.ColumnName = {};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Sudoku Solver'};
            app.ResolveButton = uibutton(app.UIFigure, 'push');
            app.ResolveButton.ButtonPushedFcn = createCallbackFcn(app, @ResolveButtonPushed, true);
            app.ResolveButton.Text = {'Resolve'; ''};
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SudokuSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end