classdef SudokuSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TextArea         matlab.ui.control.TextArea
        ResolveButton    matlab.ui.control.Button
        LoadimageButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    properties (Access = private)
        imagen % Imagen cargada
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Resize = 'off';
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes.Visible = 'off';
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            [file,~]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            app.imagen = imread(file);
            app.UIAxes.Visible = 'on';
            imshow(app.imagen, 'parent', app.UIAxes);
        end

        % Button pushed function: ResolveButton
        function ResolveButtonPushed(app, event)
    [MSudokus,~] = sudoku(app.imagen);
    
    cla(app.UIAxes)
    
    imagefondo=imread('plantilla.jpg');
    figure (25)
    factor_cambio_x=length(imagefondo(:,1,1))/18;
    factor_cambio_y=length(imagefondo(1,:,1))/18;
    
    axis([0 length(imagefondo(:,1)) 0 length(imagefondo(:,1))]);
    axis ij;
    axis off;
    imshow(imagefondo,'parent',app.UIAxes), hold on;
     %imagesc(MSudokus.Matrix)  
     a=MSudokus;
    conty=0;
    for i=1:length(a(1).SolveMatrix)
        contx=0;
        for j=1:length(a(1).SolveMatrix)
    
       % [x,y]=ind2sub(i,j)
    
        text(app.UIAxes,(j+contx)*factor_cambio_x -2 ,(i+conty)*factor_cambio_y ,num2str(a(1).SolveMatrix(i,j)),'FontSize',12)
        contx=contx+1;
        end
        conty=conty+1;
    end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Sudoku Solver'};
            app.ResolveButton = uibutton(app.UIFigure, 'push');
            app.ResolveButton.ButtonPushedFcn = createCallbackFcn(app, @ResolveButtonPushed, true);
            app.ResolveButton.Text = {'Resolve'; ''};
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SudokuSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end